classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Menu                matlab.ui.container.Menu
        Menu2               matlab.ui.container.Menu
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        openDir             matlab.ui.control.Button
        deleteAlignment     matlab.ui.control.Button
        ToFrameEditField    matlab.ui.control.EditField
        ToFrameLabel        matlab.ui.control.Label
        FromFrameEditField  matlab.ui.control.EditField
        FromFrameLabel      matlab.ui.control.Label
        SaveButton          matlab.ui.control.Button
        ShortenedLamp       matlab.ui.control.Lamp
        ShortenedLampLabel  matlab.ui.control.Label
        AlignedLamp         matlab.ui.control.Lamp
        AlignedLampLabel    matlab.ui.control.Label
        LogsButton          matlab.ui.control.Button
        AllButton           matlab.ui.control.Button
        AlignButton         matlab.ui.control.Button
        ShortenButton       matlab.ui.control.Button
        DropDown            matlab.ui.control.DropDown
        Label               matlab.ui.control.Label
        RightPanel          matlab.ui.container.Panel
        Slider              matlab.ui.control.Slider
        SliderLabel         matlab.ui.control.Label
        playPause           matlab.ui.control.StateButton
        UIAxes2             matlab.ui.control.UIAxes
        imageaxes           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        playPauseTimer timer% Description
    end
    
    methods (Access = private)
        
        function results = startup(app)
            app.playPauseTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.08, 'TimerFcn', @play_timer_callback);
            start(app.myTimer);
        end
        function play_timer_callback(app)
            current_value = get(slider, 'Value');
            if current_value < get(slider, 'Max')
                set(slider, 'Value', current_value + 1);
                slider_callback(slider);
            else
                stop(play_timer);
                set(play_pause_button, 'CData', play_icon);
                is_playing = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            path = app.DropDown.Value;
            [iteration, parentDir] = getIteration(path);
            % if path is null load the first one
            if isempty(path)
                path = stack_paths{1};
            end
    
            if exist(sprintf('%s//stack_info_%s.mat', parentDir, iteration), 'file')
                stack_info = load(sprintf('%s//stack_info_%s.mat', parentDir, iteration));
                assignin('base', 'stack_info', stack_info);
                stack_info = stack_info.stack_info;
            else
                img_data.img_files = dir(fullfile(path, '*.tif'));
                stack_info = initialize_stack_info(img_data);
            end
    
            % if start and end indices are set, set the shortened indicator to green
            if stack_info.shortened == true
                toggle_indicator(shortened_indicator, true);
                % Load the images
                stack_info.img_data.num_imgs = numel(stack_info.img_data.img_files(stack_info.start_index:stack_info.end_index));        
            else
                toggle_indicator(shortened_indicator, false);
                stack_info.img_data.num_imgs = numel(stack_info.img_data.img_files);
            end
            stack_info.img_data.imgs = cell(1, stack_info.img_data.num_imgs);
    
            % if displacement_n.mat exists, set the aligned indicator to green
            if stack_info.aligned == true && ~isempty(stack_info.displacements)
                toggle_indicator(aligned_indicator, true);
                % plot the displacements on ax2
                plot_displacements();
            else
                toggle_indicator(aligned_indicator, false);
            end
    
            set(to_frame, 'String', num2str(stack_info.img_data.num_imgs));
            % Initialize the slider
            set(slider, 'Min', 1, 'Max', stack_info.img_data.num_imgs, 'Value', 1, ...
                'SliderStep', [1/(stack_info.img_data.num_imgs-1) , 1/(stack_info.img_data.num_imgs-1)], 'Visible', 'On');
                
            % Display the first image
            setFrame(1);
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            %verticalScrollAmount = event.VerticalScrollAmount;
            %verticalScrollCount = event.VerticalScrollCount;
            persistent last_scroll_time;  % will retain its value between calls
            noww = datetime("now");
            if isempty(last_scroll_time)
                last_scroll_time = noww;  % initialize to the current time
            end
            time_between_scrolls = (noww - last_scroll_time) * 24 * 60 * 60;  % in seconds
            last_scroll_time = noww;  % update for next time
        
            step_size = max(1, round(1 / time_between_scrolls*0.1));  % larger step size for smaller time_between_scrolls
        
            current_value = get(slider, 'Value');
            if event.VerticalScrollCount > 0  % if scrolling down
                new_value = current_value - step_size; % decrease the value
            else  % if scrolling up
                new_value = current_value + step_size; % increase the value
            end
            new_value = max(min(new_value, get(slider, 'Max')), get(slider, 'Min')); % ensure the new value is within the slider's range
            set(slider, 'Value', new_value);  % update the slider value
            slider_callback(slider);  % call the slider's callback function to update the display
        end

        % Value changed function: playPause
        function playPauseValueChanged(app, event)
            value = app.playPause.Value;
            if is_playing
                stop(play_timer);
                set(play_pause_button, 'CData', play_icon);
            else
                start(play_timer);
                set(play_pause_button, 'CData', pause_icon);
            end
            is_playing = ~is_playing;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {114, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.ShortenButton = uibutton(app.LeftPanel, 'push');
            app.ShortenButton.Text = 'Shorten';
            app.AlignButton = uibutton(app.LeftPanel, 'push');
            app.AlignButton.Text = 'Align';
            app.AllButton = uibutton(app.LeftPanel, 'push');
            app.AllButton.Text = 'All';
            app.LogsButton = uibutton(app.LeftPanel, 'push');
            app.LogsButton.Text = 'Logs';
            app.AlignedLampLabel = uilabel(app.LeftPanel);
            app.AlignedLampLabel.Text = 'Aligned';
            app.AlignedLamp = uilamp(app.LeftPanel);
            app.ShortenedLampLabel = uilabel(app.LeftPanel);
            app.ShortenedLampLabel.Text = 'Shortened';
            app.ShortenedLamp = uilamp(app.LeftPanel);
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.FromFrameLabel = uilabel(app.LeftPanel);
            app.FromFrameLabel.Text = {'From '; 'Frame'};
            app.FromFrameEditField = uieditfield(app.LeftPanel, 'text');
            app.ToFrameLabel = uilabel(app.LeftPanel);
            app.ToFrameLabel.Text = {'To '; 'Frame'};
            app.ToFrameEditField = uieditfield(app.LeftPanel, 'text');
            app.deleteAlignment = uibutton(app.LeftPanel, 'push');
            app.deleteAlignment.Icon = fullfile(pathToMLAPP, 'bin_icon.png');
            app.deleteAlignment.Text = '';
            app.openDir = uibutton(app.LeftPanel, 'push');
            app.openDir.Icon = fullfile(pathToMLAPP, 'folder.png');
            app.openDir.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.imageaxes = uiaxes(app.RightPanel);
            app.imageaxes.XTick = [];
            app.imageaxes.YTick = [];
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.playPause = uibutton(app.RightPanel, 'state');
            app.playPause.ValueChangedFcn = createCallbackFcn(app, @playPauseValueChanged, true);
            app.playPause.Icon = fullfile(pathToMLAPP, 'play.png');
            app.playPause.Text = '';
            app.SliderLabel = uilabel(app.RightPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end